classdef FinalProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Lamp_4                 matlab.ui.control.Lamp
        Lamp_3                 matlab.ui.control.Lamp
        Lamp_2                 matlab.ui.control.Lamp
        Lamp                   matlab.ui.control.Lamp
        SolutionDropDown       matlab.ui.control.DropDown
        SolutionDropDownLabel  matlab.ui.control.Label
        TimeDelayKnob          matlab.ui.control.Knob
        TimeDelayKnobLabel     matlab.ui.control.Label
        VelocitySlider         matlab.ui.control.Slider
        VelocitySliderLabel    matlab.ui.control.Label
        StepKnob               matlab.ui.control.Knob
        StepKnobLabel          matlab.ui.control.Label
        Y1EditField            matlab.ui.control.NumericEditField
        Y1EditFieldLabel       matlab.ui.control.Label
        X1EditField            matlab.ui.control.NumericEditField
        X1EditFieldLabel       matlab.ui.control.Label
        PlaneZ                 matlab.ui.control.NumericEditField
        PlaneZLabel            matlab.ui.control.Label
        Y1                     matlab.ui.control.NumericEditField
        Y2Label                matlab.ui.control.Label
        X1                     matlab.ui.control.NumericEditField
        X2Label                matlab.ui.control.Label
        StartButton            matlab.ui.control.Button
        ComputeButton          matlab.ui.control.Button
        Y0                     matlab.ui.control.NumericEditField
        Y0EditFieldLabel       matlab.ui.control.Label
        X0                     matlab.ui.control.NumericEditField
        X0Label                matlab.ui.control.Label
        TypeDropDown           matlab.ui.control.DropDown
        TypeDropDownLabel      matlab.ui.control.Label
        ErrorPointnotpossibletocomputeLabel  matlab.ui.control.Label
        Sol4Label              matlab.ui.control.Label
        Sol3Label              matlab.ui.control.Label
        Sol2Label              matlab.ui.control.Label
        Sol1Label              matlab.ui.control.Label
        ActualLabel            matlab.ui.control.Label
        Theta3_Sol4            matlab.ui.control.NumericEditField
        Theta2_Sol4            matlab.ui.control.NumericEditField
        Theta1_Sol4            matlab.ui.control.NumericEditField
        Theta3_Sol3            matlab.ui.control.NumericEditField
        Theta2_Sol3            matlab.ui.control.NumericEditField
        Theta1_Sol3            matlab.ui.control.NumericEditField
        MoveButton             matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        CalculateButton        matlab.ui.control.Button
        Theta3_Sol2            matlab.ui.control.NumericEditField
        Theta3_Sol1            matlab.ui.control.NumericEditField
        Theta2_Sol2            matlab.ui.control.NumericEditField
        Theta2_Sol1            matlab.ui.control.NumericEditField
        Theta1_Sol2            matlab.ui.control.NumericEditField
        Theta1_Sol1            matlab.ui.control.NumericEditField
        OptionsDropDown        matlab.ui.control.DropDown
        OptionsDropDownLabel   matlab.ui.control.Label
        EndEffectorLabel       matlab.ui.control.Label
        ZEditField             matlab.ui.control.NumericEditField
        ZEditFieldLabel        matlab.ui.control.Label
        YEditField             matlab.ui.control.NumericEditField
        YEditFieldLabel        matlab.ui.control.Label
        XEditField             matlab.ui.control.NumericEditField
        XEditFieldLabel        matlab.ui.control.Label
        KeySTheta3Label        matlab.ui.control.Label
        KeyATheta3Label        matlab.ui.control.Label
        KeyETheta2Label        matlab.ui.control.Label
        KeyRTheta2Label        matlab.ui.control.Label
        KeyWTheta1Label        matlab.ui.control.Label
        KeyQTheta1Label        matlab.ui.control.Label
        Theta3EditField        matlab.ui.control.NumericEditField
        Theta3EditFieldLabel   matlab.ui.control.Label
        Theta2EditField        matlab.ui.control.NumericEditField
        Theta2EditFieldLabel   matlab.ui.control.Label
        Theta1EditField        matlab.ui.control.NumericEditField
        Theta1EditFieldLabel   matlab.ui.control.Label
        ConnectionLamp         matlab.ui.control.Lamp
        ConnectionLampLabel    matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        s
        tracangles % Description
        aproach % save aproach
        leave % save leaving
        solutions % Description
        ids % Description
    end
    methods (Access = private)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.s = serialport("COM9", 9600);
            configureTerminator(app.s,"LF") ;
            app.ConnectionLamp.Color = "green";
            
            app.Theta1EditField.Value = 0;
            app.Theta2EditField.Value = 0;
            app.Theta3EditField.Value = 0;
            
            % Soluciones
            app.Theta3_Sol2.Visible = "off";
            app.Theta3_Sol1.Visible = "off";
            app.Theta2_Sol2.Visible = "off";
            app.Theta2_Sol1.Visible = "off";
            app.Theta1_Sol2.Visible = "off";
            app.Theta1_Sol1.Visible = "off";
            app.Theta3_Sol3.Visible = "off";
            app.Theta2_Sol3.Visible = "off";
            app.Theta1_Sol3.Visible = "off";
            app.Theta1_Sol4.Visible = "off";
            app.Theta2_Sol4.Visible = "off";
            app.Theta3_Sol4.Visible = "off";
            app.ActualLabel.Visible = "off";
            app.Sol1Label.Visible = "off";
            app.Sol2Label.Visible = "off";
            app.Sol3Label.Visible = "off";
            app.Sol4Label.Visible = "off";
            % User Options
            app.Label.Visible = "off";
            app.CalculateButton.Visible = "off";
            app.MoveButton.Visible = "off";
            %Messages 
            app.ErrorPointnotpossibletocomputeLabel.Visible = "off";
            %Trajectories
            app.TypeDropDown.Visible = "off";
            app.TypeDropDownLabel.Visible = "off";
            app.TypeDropDown.Enable = "off";
            
            app.StartButton.Visible = "off";
            app.ComputeButton.Visible = "off";
            app.X0.Visible = "off";
            app.Y0.Visible = "off";
            app.X1.Visible = "off";
            app.Y1.Visible = "off";
            app.PlaneZ.Visible = "off";
            app.X0Label.Visible = "off";
            app.X2Label.Visible = "off";
            app.Y2Label.Visible = "off";
            app.X0Label.Visible = "off";
            app.Y0EditFieldLabel.Visible = "off";
            app.PlaneZLabel.Visible = "off";
            
            app.Y1EditField.Visible = "off";
            app.X1EditField.Visible = "off";
            app.Y1EditFieldLabel.Visible = "off";
            app.X1EditFieldLabel.Visible = "off";
        
            %Resoluution
            app.StepKnob.Visible = "off";
            app.StepKnobLabel.Visible = "off";
            app.TimeDelayKnob.Visible = "off";
            app.TimeDelayKnobLabel.Visible = "off";
            app.SolutionDropDown.Visible = "off";
            app.SolutionDropDownLabel.Visible = "off";
            
            % Lamps
            app.Lamp_4.Visible = "off";
            app.Lamp_3.Visible = "off";
            app.Lamp_2.Visible = "off";
            app.Lamp.Visible = "off";
            
            %Others

            theta1 = string(app.Theta1EditField.Value+150);
            theta2 = string(app.Theta2EditField.Value+60);
            theta3 = string(app.Theta3EditField.Value+150);
            z = strcat('<', theta1, ", ", theta2, ", ", theta3,",",...
                string(app.VelocitySlider.Value),">");
            writeline(app.s, z);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            if app.OptionsDropDown.Value == "DGM"
                key = event.Key;
                switch key
                    case 'q'
                        if app.Theta1EditField.Value <= 149
                            app.Theta1EditField.Value = app.Theta1EditField.Value+1;
                        end
                    case 'w'
                        if app.Theta1EditField.Value >= -149
                            app.Theta1EditField.Value = app.Theta1EditField.Value-1;
                        end
                    case 'e'
                        if app.Theta2EditField.Value <= 149
                            app.Theta2EditField.Value = app.Theta2EditField.Value+1;
                        end
                    case 'r'
                        if app.Theta2EditField.Value >= -149
                            app.Theta2EditField.Value = app.Theta2EditField.Value-1;
                        end
                    case 'a'
                        if app.Theta3EditField.Value <= 89
                            app.Theta3EditField.Value = app.Theta3EditField.Value+1;
                        end
                    case 's'
                        if app.Theta3EditField.Value >= -89
                            app.Theta3EditField.Value = app.Theta3EditField.Value-1;
                        end
                end
            end
        end

        % Key release function: UIFigure
        function UIFigureKeyRelease(app, event)
            if app.OptionsDropDown.Value == "DGM"
                hold (app.UIAxes,'on')
                key = event.Key;
                if key == "q" || key == "w" || key == "e" || key == "r"...
                        || key == "a" || key == "s"

                    % Compute DGM
                    [px, py, pz] = computeDGM(...
                        app.Theta1EditField.Value,...
                        app.Theta2EditField.Value,...
                        app.Theta3EditField.Value);

                    app.XEditField.Value = px;
                    app.YEditField.Value = py;
                    app.ZEditField.Value = pz;
                    
                    % Send to arduino
                    theta1 = string(app.Theta1EditField.Value+150);
                    theta2 = string(app.Theta2EditField.Value+60);
                    theta3 = string(app.Theta3EditField.Value+150);
                    z = strcat('<', theta1, ", ", theta2, ", ", theta3,", ",...
                            string(app.VelocitySlider.Value),">")
                    writeline(app.s, z);
                    
                    %Plot
                    plot3(app.UIAxes,px, py, pz, 'o');
                end
            end
        end

        % Value changed function: OptionsDropDown
        function OptionsDropDownValueChanged(app, event)
            value = app.OptionsDropDown.Value;
            
            % Layout Inverse Geometric Model
            if value == "IGM"
                cla(app.UIAxes)
                app.KeySTheta3Label.Visible = "off";
                app.KeyATheta3Label.Visible = "off";
                app.KeyETheta2Label.Visible = "off";
                app.KeyRTheta2Label.Visible = "off";
                app.KeyWTheta1Label.Visible = "off";
                app.KeyQTheta1Label.Visible = "off";
                % Soluciones
                app.Theta3_Sol2.Visible = "on";
                app.Theta3_Sol1.Visible = "on";
                app.Theta2_Sol2.Visible = "on";
                app.Theta2_Sol1.Visible = "on";
                app.Theta1_Sol2.Visible = "on";
                app.Theta1_Sol1.Visible = "on";
                app.Theta3_Sol3.Visible = "on";
                app.Theta2_Sol3.Visible = "on";
                app.Theta1_Sol3.Visible = "on";
                app.Theta1_Sol4.Visible = "on";
                app.Theta2_Sol4.Visible = "on";
                app.Theta3_Sol4.Visible = "on";
                app.ActualLabel.Visible = "on";
                app.Sol1Label.Visible = "on";
                app.Sol2Label.Visible = "on";
                app.Sol3Label.Visible = "on";
                app.Sol4Label.Visible = "on";
                %Functionality
                app.Theta1EditField.Enable = "on";
                app.Theta2EditField.Enable = "on";
                app.Theta3EditField.Enable = "on";
                % User Options
                app.Label.Visible = "on";
                app.CalculateButton.Visible = "on";
                app.MoveButton.Visible = "on";
                % Graph
                app.UIAxes.Visible = "off";
                %Trajectories
                app.TypeDropDown.Visible = "off";
                app.TypeDropDownLabel.Visible = "off";
                app.TypeDropDown.Enable = "off";
                
                app.StartButton.Visible = "off";
                app.ComputeButton.Visible = "off";
                app.X0.Visible = "off";
                app.Y0.Visible = "off";
                app.X1.Visible = "off";
                app.Y1.Visible = "off";
                app.PlaneZ.Visible = "off";
                app.X0Label.Visible = "off";
                app.X2Label.Visible = "off";
                app.Y2Label.Visible = "off";
                app.X0Label.Visible = "off";
                app.Y0EditFieldLabel.Visible = "off";
                app.PlaneZLabel.Visible = "off";
                
                app.Y1EditField.Visible = "off";
                app.X1EditField.Visible = "off";
                app.Y1EditFieldLabel.Visible = "off";
                app.X1EditFieldLabel.Visible = "off";
                
                %Resoluution
                app.StepKnob.Visible = "off";
                app.StepKnobLabel.Visible = "off";
                app.TimeDelayKnob.Visible = "off";
                app.TimeDelayKnobLabel.Visible = "off";
                
                app.SolutionDropDown.Visible = "on";
                app.SolutionDropDownLabel.Visible = "on";
                
                app.Lamp_4.Visible = "on";
                app.Lamp_3.Visible = "on";
                app.Lamp_2.Visible = "on";
                app.Lamp.Visible = "on";
            elseif value == "TRAJECTORIES"
                % Indi
                app.KeySTheta3Label.Visible = "off";
                app.KeyATheta3Label.Visible = "off";
                app.KeyETheta2Label.Visible = "off";
                app.KeyRTheta2Label.Visible = "off";
                app.KeyWTheta1Label.Visible = "off";
                app.KeyQTheta1Label.Visible = "off";
                % Soluciones
                app.Theta3_Sol2.Visible = "off";
                app.Theta3_Sol1.Visible = "off";
                app.Theta2_Sol2.Visible = "off";
                app.Theta2_Sol1.Visible = "off";
                app.Theta1_Sol2.Visible = "off";
                app.Theta1_Sol1.Visible = "off";
                app.Theta3_Sol3.Visible = "off";
                app.Theta2_Sol3.Visible = "off";
                app.Theta1_Sol3.Visible = "off";
                app.Theta1_Sol4.Visible = "off";
                app.Theta2_Sol4.Visible = "off";
                app.Theta3_Sol4.Visible = "off";
                app.ActualLabel.Visible = "off";
                app.Sol1Label.Visible = "off";
                app.Sol2Label.Visible = "off";
                app.Sol3Label.Visible = "off";
                app.Sol4Label.Visible = "off";
                % User Options
                app.Label.Visible = "off";
                app.CalculateButton.Visible = "off";
                app.MoveButton.Visible = "off";
                % Graph
                app.UIAxes.Visible = "on";
                %Trajectories
                app.TypeDropDown.Visible = "on";
                app.TypeDropDownLabel.Visible = "on";
                app.TypeDropDown.Enable = "on";
                
                app.StartButton.Visible = "on";
                app.ComputeButton.Visible = "on";
                app.X0.Visible = "on";
                app.Y0.Visible = "on";
                app.X1.Visible = "on";
                app.Y1.Visible = "on";
                app.PlaneZ.Visible = "on";
                app.X0Label.Visible = "on";
                app.X2Label.Visible = "on";
                app.Y2Label.Visible = "on";
                app.X0Label.Visible = "on";
                app.Y0EditFieldLabel.Visible = "on";
                app.PlaneZLabel.Visible = "on";
                
              
                app.Y1EditField.Visible = "on";
                app.X1EditField.Visible = "on";
                app.Y1EditFieldLabel.Visible = "on";
                app.X1EditFieldLabel.Visible = "on";
                
                %Resoluution
                app.StepKnob.Visible = "on";
                app.StepKnobLabel.Visible = "on";
                
                app.X1EditField.Visible = "off";
                app.X1EditFieldLabel.Visible = "off";
                app.Y1EditField.Visible = "off";
                app.Y1EditFieldLabel.Visible = "off";
                
                app.TimeDelayKnob.Visible = "on";
                app.TimeDelayKnobLabel.Visible = "on";
                
                app.SolutionDropDown.Visible = "off";
                app.SolutionDropDownLabel.Visible = "off";

                app.Lamp_4.Visible = "off";
                app.Lamp_3.Visible = "off";
                app.Lamp_2.Visible = "off";
                app.Lamp.Visible = "off";
            
                
            else
                app.KeySTheta3Label.Visible = "on";
                app.KeyATheta3Label.Visible = "on";
                app.KeyETheta2Label.Visible = "on";
                app.KeyRTheta2Label.Visible = "on";
                app.KeyWTheta1Label.Visible = "on";
                app.KeyQTheta1Label.Visible = "on";
                
                % Soluciones
                app.Theta3_Sol2.Visible = "off";
                app.Theta3_Sol1.Visible = "off";
                app.Theta2_Sol2.Visible = "off";
                app.Theta2_Sol1.Visible = "off";
                app.Theta1_Sol2.Visible = "off";
                app.Theta1_Sol1.Visible = "off";
                app.Theta3_Sol3.Visible = "off";
                app.Theta2_Sol3.Visible = "off";
                app.Theta1_Sol3.Visible = "off";
                app.Theta1_Sol4.Visible = "off";
                app.Theta2_Sol4.Visible = "off";
                app.Theta3_Sol4.Visible = "off";
                app.ActualLabel.Visible = "off";
                app.Sol1Label.Visible = "off";
                app.Sol2Label.Visible = "off";
                app.Sol3Label.Visible = "off";
                app.Sol4Label.Visible = "off";
                % User Options
                app.Label.Visible = "off";
                app.CalculateButton.Visible = "off";
                app.MoveButton.Visible = "off";
                %Messages
                app.ErrorPointnotpossibletocomputeLabel.Visible = "off";
                % Graph
                app.UIAxes.Visible = "on";
                %Trajectories
                app.TypeDropDown.Visible = "off";
                app.TypeDropDownLabel.Visible = "off";
                app.TypeDropDown.Enable = "off";
                
                app.StartButton.Visible = "off";
                app.ComputeButton.Visible = "off";
                app.X0.Visible = "off";
                app.Y0.Visible = "off";
                app.X1.Visible = "off";
                app.Y1.Visible = "off";
                app.PlaneZ.Visible = "off";
                app.X0Label.Visible = "off";
                app.X2Label.Visible = "off";
                app.Y2Label.Visible = "off";
                app.X0Label.Visible = "off";
                app.Y0EditFieldLabel.Visible = "off";
                app.PlaneZLabel.Visible = "off";
                
                app.Y1EditField.Visible = "off";
                app.X1EditField.Visible = "off";
                app.Y1EditFieldLabel.Visible = "off";
                app.X1EditFieldLabel.Visible = "off";
                
                %Resoluution
                app.StepKnob.Visible = "off";
                app.StepKnobLabel.Visible = "off";
                app.TimeDelayKnob.Visible = "off";
                app.TimeDelayKnobLabel.Visible = "off";
                app.SolutionDropDown.Visible = "off";
                app.SolutionDropDownLabel.Visible = "off";

                app.Lamp_4.Visible = "off";
                app.Lamp_3.Visible = "off";
                app.Lamp_2.Visible = "off";
                app.Lamp.Visible = "off";

            end
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
% Compute the IGM
[solut, id] = compute_IGM(app.XEditField.Value, ...
                          app.YEditField.Value,...
                          app.ZEditField.Value, ...
                          app.Theta1EditField.Value, ...
                          app.Theta2EditField.Value, ...
                          app.Theta3EditField.Value);

if isempty(solut) == 0 && solut(id(1), 1)<= 150 && ...
   solut(id(1), 1) >= -150 && solut(id(1), 2) <= 150 ...
   && solut(id(1), 2) >= -150 && solut(id(1), 3) <= 100 ...
   && solut(id(1), 3) >= -100
        
    app.Theta1_Sol1.Value = solut(id(1), 1);
    app.Theta2_Sol1.Value = solut(id(1), 2);
    app.Theta3_Sol1.Value = solut(id(1), 3);
    
    app.Theta1_Sol2.Value = solut(id(2), 1);
    app.Theta2_Sol2.Value = solut(id(2), 2);
    app.Theta3_Sol2.Value = solut(id(2), 3);
    
    app.Theta1_Sol3.Value = solut(id(3), 1);
    app.Theta2_Sol3.Value = solut(id(3), 2);
    app.Theta3_Sol3.Value = solut(id(3), 3);
    
    app.Theta1_Sol4.Value = solut(id(4), 1);
    app.Theta2_Sol4.Value = solut(id(4), 2);
    app.Theta3_Sol4.Value = solut(id(4), 3);
    
    app.solutions = solut;
    app.ids = id;
    
    app.ErrorPointnotpossibletocomputeLabel.Visible = "off";
    app.MoveButton.Enable ="on";
else
    app.ErrorPointnotpossibletocomputeLabel.Visible = "on";
    app.MoveButton.Enable = "off";
end 

if solut(id(1), 1) < 150 && solut(id(1), 1) > -150 &&...
        solut(id(1), 2) < 150 &&  solut(id(1), 2) >-150&&...
        solut(id(1), 3) < 90 &&  solut(id(1), 3)  > -90
    app.Lamp_4.Color = "green"
else 
    app.Lamp_4.Color = "red"
end


if solut(id(2), 1) < 150 && solut(id(2), 1) > -150 &&...
        solut(id(2), 2) < 150 &&  solut(id(2), 2) >-150&&...
        solut(id(2), 3) < 90 &&  solut(id(2), 3)  > -90
    app.Lamp_3.Color = "green"
else 
    app.Lamp_3.Color = "red"
end


if solut(id(3), 1) < 150 && solut(id(3), 1) > -150 &&...
        solut(id(3), 2) < 150 &&  solut(id(3), 2) >-150&&...
        solut(id(3), 3) < 90 &&  solut(id(3), 3)  > -90
    app.Lamp.Color = "green"
else 
    app.Lamp.Color = "red"
end


if solut(id(4), 1) < 150 && solut(id(4), 1) > -150 &&...
        solut(id(4), 2) < 150 &&  solut(id(4), 2) >-150&&...
        solut(id(4), 3) < 90 &&  solut(id(4), 3)  > -90
    app.Lamp_2.Color = "green"
else 
    app.Lamp_2.Color = "red"
end


        end

        % Button pushed function: MoveButton
        function MoveButtonPushed(app, event)
            op = 1;
            if app.SolutionDropDown.Value == "1"
                op = 1;
            elseif app.SolutionDropDown.Value == "2"
                op = 2;
            elseif app.SolutionDropDown.Value == "3"
                op = 3;
            elseif app.SolutionDropDown.Value == "4"
                op = 4;
            end
            
            
            % Inverse Send solution to arduino 
            app.SolutionDropDown.Value
            app.Theta1EditField.Value = app.solutions(op, 1);
            app.Theta2EditField.Value = app.solutions(op, 2);
            app.Theta3EditField.Value = app.solutions(op, 3);
            
            theta1 = string(app.Theta1EditField.Value+150);
            theta2 = string(app.Theta2EditField.Value+60);
            theta3 = string(app.Theta3EditField.Value+150);
            z = strcat('<', theta1, ", ", theta2, ", ", theta3,", ",...
                string(app.VelocitySlider.Value),">");
            writeline(app.s, z);
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            
            % Compute for linear trayectory
            if app.TypeDropDown.Value == "Line"
                hold (app.UIAxes,'off')
                [q, qd] = moveL(app.X0.Value,...
                    app.X1.Value,app.Y0.Value,...
                                 app.Y1.Value, ...
                                 app.StepKnob.Value);

                app.aproach = [q(1,1); q(2,1); 0];
                app.leave = [q(1,length(q)); q(2,length(q)); 0];
                
                for i=1:length(q)
                    [x, idx] = compute_IGM(q(1,i),q(2,i),app.PlaneZ.Value,...
                        app.Theta1EditField.Value,...
                        app.Theta2EditField.Value,...
                        app.Theta3EditField.Value);
                    angles(i,:) = x(2,:);
                    xplane(i) = app.PlaneZ.Value;
                end
                plot3(app.UIAxes,q(1,:), q(2,:),xplane,'o');
                app.tracangles = angles;
            
            % Compute for circular trayectory
            elseif app.TypeDropDown.Value == "Arc"

                hold (app.UIAxes,'off')
                q = moveC(app.X0.Value,...
                    app.X1EditField.Value,...
                    app.X1.Value, ...
                    app.Y0.Value,...
                    app.Y1EditField.Value, ...
                    app.Y1.Value, ...
                    app.StepKnob.Value);
                
                    app.aproach = [q(1,1); q(2,1); 0]
                    app.leave = [q(1,length(q)); q(2,length(q)); 0];
                
                for i=1:length(q)
                    [x, idx] = compute_IGM(q(1,i),q(2,i),app.PlaneZ.Value,...
                        app.Theta1EditField.Value,...
                        app.Theta2EditField.Value,...
                        app.Theta3EditField.Value)
                    
                    angles(i,:) = x(2,:);
                    xplane(i) = app.PlaneZ.Value;
                end
                
                plot3(app.UIAxes,q(1,:), q(2,:),xplane,'o');
                app.tracangles = angles;
            end
                
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            hold (app.UIAxes,'on')
            % Aproach
            [x, idx] = compute_IGM(app.aproach(1), app.aproach(2),...
                app.aproach(3), 0, 0, 0)
            aprx = x(2,:)

            app.Theta1EditField.Value = aprx(1);
            app.Theta2EditField.Value = aprx(2);
            app.Theta3EditField.Value = aprx(3);

            [px, py, pz] = computeDGM(...
                app.Theta1EditField.Value,...
                app.Theta2EditField.Value,...
                app.Theta3EditField.Value);

            app.XEditField.Value = px;
            app.YEditField.Value = py;
            app.ZEditField.Value = pz;


            theta1 = string(app.Theta1EditField.Value+150);
            theta2 = string(app.Theta2EditField.Value+60);
            theta3 = string(app.Theta3EditField.Value+150);
            z = strcat('<', theta1, ", ", theta2, ", ", theta3,", ",...
                string(app.VelocitySlider.Value),">");
            writeline(app.s, z);
            pause(3)
            plot3(app.UIAxes,px, py, pz, 'r-o');
            pause(1)


            for n=1:length(app.tracangles)
                app.Theta1EditField.Value = app.tracangles(n,1);
                app.Theta2EditField.Value = app.tracangles(n,2);
                app.Theta3EditField.Value = app.tracangles(n,3);

                [px, py, pz] = computeDGM(...
                    app.Theta1EditField.Value,...
                    app.Theta2EditField.Value,...
                    app.Theta3EditField.Value);

                app.XEditField.Value = px;
                app.YEditField.Value = py;
                app.ZEditField.Value = pz;

                plot3(app.UIAxes,px, py, pz, 'r-o');
                pause(app.TimeDelayKnob.Value);

                theta1 = string(app.Theta1EditField.Value+150);
                theta2 = string(app.Theta2EditField.Value+60);
                theta3 = string(app.Theta3EditField.Value+150);
                z = strcat('<', theta1, ", ", theta2, ", ", theta3,", ",...
                string(app.VelocitySlider.Value),">");
                writeline(app.s, z);
            end
            % dont look bback
            [x, idx] = compute_IGM(app.leave(1), app.leave(2),...
                app.leave(3), 0, 0, 0)
            lev = x(2,:)

            app.Theta1EditField.Value = lev(1);
            app.Theta2EditField.Value = lev(2);
            app.Theta3EditField.Value = lev(3);

            [px, py, pz] = computeDGM(...
                app.Theta1EditField.Value,...
                app.Theta2EditField.Value,...
                app.Theta3EditField.Value);

            app.XEditField.Value = px;
            app.YEditField.Value = py;
            app.ZEditField.Value = pz;


            theta1 = string(app.Theta1EditField.Value+150);
            theta2 = string(app.Theta2EditField.Value+60);
            theta3 = string(app.Theta3EditField.Value+150);
            z = strcat('<', theta1, ", ", theta2, ", ", theta3,", ",...
                string(app.VelocitySlider.Value),">");
            writeline(app.s, z);
            pause(3)
            plot3(app.UIAxes,px, py, pz, 'r-o');
            pause(1)

        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            if value =="Arc"
                app.X1EditField.Visible = "on";
                app.X1EditFieldLabel.Visible = "on";
                app.Y1EditField.Visible = "on";
                app.Y1EditFieldLabel.Visible = "on"
            else
                app.X1EditField.Visible = "off";
                app.X1EditFieldLabel.Visible = "off";
                app.Y1EditField.Visible = "off";
                app.Y1EditFieldLabel.Visible = "off";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyReleaseFcn = createCallbackFcn(app, @UIFigureKeyRelease, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'End Effector')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.ConnectionLampLabel = uilabel(app.UIFigure);
            app.ConnectionLampLabel.Text = 'Connection';
            app.ConnectionLamp = uilamp(app.UIFigure);
            app.Theta1EditFieldLabel = uilabel(app.UIFigure);
            app.Theta1EditFieldLabel.Text = 'Theta 1';
            app.Theta1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta1EditField.Limits = [-150 150];
            app.Theta1EditField.ValueDisplayFormat = '%.2f';
            app.Theta2EditFieldLabel = uilabel(app.UIFigure);
            app.Theta2EditFieldLabel.Text = 'Theta 2';
            app.Theta2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta2EditField.Limits = [-150 150];
            app.Theta2EditField.ValueDisplayFormat = '%.2f';
            app.Theta3EditFieldLabel = uilabel(app.UIFigure);
            app.Theta3EditFieldLabel.Text = 'Theta 3';
            app.Theta3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Theta3EditField.Limits = [-100 100];
            app.Theta3EditField.ValueDisplayFormat = '%.2f';
            app.KeyQTheta1Label = uilabel(app.UIFigure);
            app.KeyQTheta1Label.Text = 'Key "Q"  Theta 1 +';
            app.KeyWTheta1Label = uilabel(app.UIFigure);
            app.KeyWTheta1Label.Text = 'Key "W"  Theta 1 -';
            app.KeyRTheta2Label = uilabel(app.UIFigure);
            app.KeyRTheta2Label.Text = 'Key "R"  Theta 2 -';
            app.KeyETheta2Label = uilabel(app.UIFigure);
            app.KeyETheta2Label.Text = 'Key "E"  Theta 2 +';
            app.KeyATheta3Label = uilabel(app.UIFigure);
            app.KeyATheta3Label.Text = 'Key "A"  Theta 3 +';
            app.KeySTheta3Label = uilabel(app.UIFigure);
            app.KeySTheta3Label.Text = 'Key "S"  Theta 3 -';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.ValueDisplayFormat = '%.2f';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.ValueDisplayFormat = '%.2f';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.ValueDisplayFormat = '%.2f';
            app.EndEffectorLabel = uilabel(app.UIFigure);
            app.EndEffectorLabel.Text = 'End Effector';
            app.OptionsDropDownLabel = uilabel(app.UIFigure);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.UIFigure);
            app.OptionsDropDown.Items = {'DGM', 'IGM', 'TRAJECTORIES'};
            app.OptionsDropDown.ValueChangedFcn = createCallbackFcn(app, @OptionsDropDownValueChanged, true);
            app.OptionsDropDown.Value = 'DGM';
            app.Theta1_Sol1 = uieditfield(app.UIFigure, 'numeric');
            app.Theta1_Sol1.ValueDisplayFormat = '%.2f';
            app.Theta1_Sol2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta1_Sol2.ValueDisplayFormat = '%.2f';
            app.Theta2_Sol1 = uieditfield(app.UIFigure, 'numeric');
            app.Theta2_Sol1.ValueDisplayFormat = '%.2f';
            app.Theta2_Sol2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta2_Sol2.ValueDisplayFormat = '%.2f';
            app.Theta3_Sol1 = uieditfield(app.UIFigure, 'numeric');
            app.Theta3_Sol1.ValueDisplayFormat = '%.2f';
            app.Theta3_Sol2 = uieditfield(app.UIFigure, 'numeric');
            app.Theta3_Sol2.ValueDisplayFormat = '%.2f';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'In the end effector field put the position vector you want the robot achieve, then press calculate to perform the IGM and finally, press move.  ';
            app.MoveButton = uibutton(app.UIFigure, 'push');
            app.MoveButton.ButtonPushedFcn = createCallbackFcn(app, @MoveButtonPushed, true);
            app.MoveButton.Text = 'Move';
            app.Theta1_Sol3 = uieditfield(app.UIFigure, 'numeric');
            app.Theta1_Sol3.ValueDisplayFormat = '%.2f';
            app.Theta2_Sol3 = uieditfield(app.UIFigure, 'numeric');
            app.Theta2_Sol3.ValueDisplayFormat = '%.2f';
            app.Theta3_Sol3 = uieditfield(app.UIFigure, 'numeric');
            app.Theta3_Sol3.ValueDisplayFormat = '%.2f';
            app.Theta1_Sol4 = uieditfield(app.UIFigure, 'numeric');
            app.Theta1_Sol4.ValueDisplayFormat = '%.2f';
            app.Theta2_Sol4 = uieditfield(app.UIFigure, 'numeric');
            app.Theta2_Sol4.ValueDisplayFormat = '%.2f';
            app.Theta3_Sol4 = uieditfield(app.UIFigure, 'numeric');
            app.Theta3_Sol4.ValueDisplayFormat = '%.2f';
            app.ActualLabel = uilabel(app.UIFigure);
            app.ActualLabel.Text = {'Actual'; ''};
            app.Sol1Label = uilabel(app.UIFigure);
            app.Sol1Label.Text = 'Sol 1';
            app.Sol2Label = uilabel(app.UIFigure);
            app.Sol2Label.Text = 'Sol 2';
            app.Sol3Label = uilabel(app.UIFigure);
            app.Sol3Label.Text = 'Sol 3';
            app.Sol4Label = uilabel(app.UIFigure);
            app.Sol4Label.Text = 'Sol 4';
            app.ErrorPointnotpossibletocomputeLabel = uilabel(app.UIFigure);
            app.ErrorPointnotpossibletocomputeLabel.Text = 'Error! Point not possible to compute';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Line', 'Arc'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Line';
            app.X0Label = uilabel(app.UIFigure);
            app.X0Label.Text = 'X0';
            app.X0 = uieditfield(app.UIFigure, 'numeric');
            app.X0.Value = 20;
            app.Y0EditFieldLabel = uilabel(app.UIFigure);
            app.Y0EditFieldLabel.Text = 'Y0';
            app.Y0 = uieditfield(app.UIFigure, 'numeric');
            app.Y0.Value = -6;
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.X2Label = uilabel(app.UIFigure);
            app.X2Label.Text = 'X2';
            app.X1 = uieditfield(app.UIFigure, 'numeric');
            app.X1.Value = 20;
            app.Y2Label = uilabel(app.UIFigure);
            app.Y2Label.Text = 'Y2';
            app.Y1 = uieditfield(app.UIFigure, 'numeric');
            app.Y1.Value = 6;
            app.PlaneZLabel = uilabel(app.UIFigure);
            app.PlaneZLabel.Text = 'Plane Z';
            app.PlaneZ = uieditfield(app.UIFigure, 'numeric');
            app.PlaneZ.Value = -2;
            app.X1EditFieldLabel = uilabel(app.UIFigure);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.UIFigure, 'numeric');
            app.X1EditField.Value = 27;
            app.Y1EditFieldLabel = uilabel(app.UIFigure);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Y1EditField.Value = 3;
            app.StepKnobLabel = uilabel(app.UIFigure);
            app.StepKnobLabel.Text = 'Step';
            app.StepKnob = uiknob(app.UIFigure, 'continuous');
            app.StepKnob.Limits = [0.1 1];
            app.StepKnob.Value = 0.5;
            app.VelocitySliderLabel = uilabel(app.UIFigure);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.UIFigure);
            app.VelocitySlider.Limits = [10 100];
            app.VelocitySlider.Value = 10;
            app.TimeDelayKnobLabel = uilabel(app.UIFigure);
            app.TimeDelayKnobLabel.Text = 'Time Delay';
            app.TimeDelayKnob = uiknob(app.UIFigure, 'continuous');
            app.TimeDelayKnob.Limits = [0.2 2];
            app.TimeDelayKnob.Value = 0.2;
            app.SolutionDropDownLabel = uilabel(app.UIFigure);
            app.SolutionDropDownLabel.Text = 'Solution';
            app.SolutionDropDown = uidropdown(app.UIFigure);
            app.SolutionDropDown.Items = {'1', '2', '3', '4'};
            app.SolutionDropDown.Value = '1';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp_3 = uilamp(app.UIFigure);
            app.Lamp_4 = uilamp(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end